classdef EX_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Button                matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        PSNRdBEditFieldLabel  matlab.ui.control.Label
        PSNRdBEditField       matlab.ui.control.NumericEditField
        Button_4              matlab.ui.control.Button
    end

    
    methods (Access = private)
        function [ PSNR ] = PSNR_cal(~,figure1,figure2,bit)
            img=double(figure2);
            imgn=double(figure1);
            Size=size(figure1);
            height=Size(1);
            width=Size(2);
            MAX=2^bit-1;          %图像灰度级数量
            PMSE=sum(sum((img-imgn).^2))/(height*width)/MAX^2; %均方差
            PSNR=-10*log10(PMSE);           %峰值信噪比
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed2(app, event)
        %-------------------------------原图像--------------------------------
        file="lena.bmp";
        figure=imread(file); 
        axis(app.UIAxes,'off'); %隐藏坐标轴
        imshow(figure,'Parent',app.UIAxes); %显示Lena原图
        %------------------------------傅立叶变换频谱--------------------------
        fourier = fft2(figure); 
        shiftf=fftshift(fourier); 
        R=real(shiftf); % 取傅立叶变换的实部
        I=imag(shiftf); % 取傅立叶变换的虚部
        spectrum = sqrt(R.^2+I.^2); % 计算频谱幅值
        spectrum_norm =(spectrum-min(min(spectrum)))/(max(max(spectrum))-min(min(spectrum)))*255;%归一化
        axis(app.UIAxes_2,'off');
        imshow(spectrum_norm,'Parent',app.UIAxes_2); % 显示原图像的频谱
        %--------------------------------傅立叶逆变换--------------------------
        Size=size(figure);
        height=Size(1);
        width=Size(2);
        spectrum2=sort(reshape(spectrum,[1,width*height])); %排序，取阈值
        threshold=spectrum2(round(width*height*0.90)); %门限
        for p=1:height
            for j=1:width
                if spectrum(p,j)<=threshold
                    R(p,j)=0;
                    I(p,j)=0;
                end
            end
        end
        refigure=ifft2(ifftshift(R+1i*I))/256 ;
        axis(app.UIAxes_3,'off');
        imshow(refigure,'Parent',app.UIAxes_3); %显示逆变化后图象
        PSNR = PSNR_cal(app,figure,256*refigure,8); %计算 PSNR
        app.PSNRdBEditField.Value=PSNR;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
         %-------------------------------原图像--------------------------------
        file="lena.bmp";
        figure=imread(file); 
        axis(app.UIAxes,'off'); %隐藏坐标轴
        imshow(figure,'Parent',app.UIAxes); %显示Lena原图
        %--------------------------离散余弦变换频谱----------------------------
        DCT=dct2(figure); %离散余弦变换
        axis(app.UIAxes_2,'off');
        imshow(abs(DCT),[0 255],'Parent',app.UIAxes_2); % 显示原图像的频谱
        %---------------------------离散余弦逆变换-----------------------------
        Size=size(DCT);
        height=Size(1);
        width=Size(2);
        DCT_sort=sort(reshape(DCT,1,width*height));%排序，取阈值
        threhold=DCT_sort(round(width*height*0.90)); %门限
        DCT(abs(DCT)<threhold)=0;
        refigure=idct2(DCT);
        imshow(refigure,[0 255],'Parent',app.UIAxes_3);
        PSNR = PSNR_cal(app,figure,refigure,8); %计算 PSNR
        app.PSNRdBEditField.Value=PSNR;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
        %----------------------------原图像-----------------------------------
        file="lena.bmp";
        figure=imread(file); 
        imshow(figure,'Parent',app.UIAxes);
        %-----------------------------哈达玛变换-------------------------------
        H=hadamard(512);
        figure=double(figure);
        DHT=H*figure*H./(512^2);
        imshow(DHT*2^10,[0,255],'Parent',app.UIAxes_2);
        %--------------------------------哈达玛逆变换--------------------------
        Size=size(figure);
        height=Size(1);
        width=Size(2);
        DHT_sort=sort(reshape(DHT,1,width*height)); %排序，取阈值
        threshold=abs(DHT_sort(round(width*height*0.90))); %门限
        DHT(abs(DHT)<=threshold)=0;
        refigure=H*DHT*H; 
        imshow(refigure,[0,255],'Parent',app.UIAxes_3);
        PSNR = PSNR_cal(app,figure,refigure,8); %计算 PSNR
        app.PSNRdBEditField.Value=PSNR;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
             closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = '傅里叶变换';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '离散余弦变换';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '哈达玛变换';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Lena原图')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '变换')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '逆变换')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.PSNRdBEditFieldLabel = uilabel(app.UIFigure);
            app.PSNRdBEditFieldLabel.Text = 'PSNR(dB)';
            app.PSNRdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '退 出';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EX_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end