classdef EX_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Button            matlab.ui.control.Button
        r1EditFieldLabel  matlab.ui.control.Label
        r1EditField       matlab.ui.control.NumericEditField
        r2EditFieldLabel  matlab.ui.control.Label
        r2EditField       matlab.ui.control.NumericEditField
        K1Label           matlab.ui.control.Label
        K1EditField       matlab.ui.control.NumericEditField
        K2EditFieldLabel  matlab.ui.control.Label
        K2EditField       matlab.ui.control.NumericEditField
        Button_2          matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes_3          matlab.ui.control.UIAxes
        UIAxes_4          matlab.ui.control.UIAxes
        UIAxes_5          matlab.ui.control.UIAxes
        UIAxes_6          matlab.ui.control.UIAxes
        TLabel            matlab.ui.control.Label
        TEditField        matlab.ui.control.NumericEditField
        aLabel            matlab.ui.control.Label
        aEditField        matlab.ui.control.NumericEditField
        bLabel            matlab.ui.control.Label
        bEditField        matlab.ui.control.NumericEditField
        rEditFieldLabel   matlab.ui.control.Label
        rEditField        matlab.ui.control.NumericEditField
        KEditFieldLabel   matlab.ui.control.Label
        KEditField        matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function [PSNR] = PSNR_cal(~,figure1,figure2,bit)
            img=double(figure2);
            imgn=double(figure1);
            Size=size(figure1);
            height=Size(1);
            width=Size(2);
            MAX=2^bit-1;          %图像灰度级数量
            MES=sum(sum((img-imgn).^2))/(height*width); %均方差
            PSNR=20*log10(MAX/sqrt(MES));           %峰值信噪比
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
             closereq();
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             %-------------------------------原图像--------------------------------
            file="lena.bmp";
            lena=imread(file);         
            axis(app.UIAxes,'off'); %隐藏坐标轴
            imshow(lena,'Parent',app.UIAxes); %显示Lena原图
            title(app.UIAxes,'Lena原图');
             %-------------------------------降质模型--------------------------------
            [x,y]=size(lena);
            v=[-x/2:x/2-1];
            u=v';            
            T=app.TEditField.Value;
            a=app.aEditField.Value;
            b=app.bEditField.Value;
            A=repmat(a.*u,1,x)+repmat(b.*v,x,1);
            H1=T/pi./A.*sin(pi.*A).*exp(-1i*pi.*A);
            H1(A==0)=T;            
             %-------------------------------降质变换--------------------------------
            fftlena=ifftshift(fft2(lena));
            Q0=fftlena.*H1;
            q0=ifft2(ifftshift(Q0));            
            axis(app.UIAxes_2,'off'); %隐藏坐标轴
            imshow(uint8(255.*mat2gray(real(q0))),'Parent',app.UIAxes_2); %显示
            peaksnr=PSNR_cal(app,lena,q0,8);     % 信噪比
            title(app.UIAxes_2,sprintf('降质图像，PSNR=%.4fdB',peaksnr));
             %-------------------------------反向滤波-1--------------------------------
            r1=app.r1EditField.Value; %r值读取                           
            [peaksnr1,q0_inv1]=inverse_filter(app,r1,x,y,H1,Q0,lena);
            axis(app.UIAxes_3,'off'); %隐藏坐标轴
            imshow(uint8(255.*mat2gray(real(q0_inv1))),'Parent',app.UIAxes_3);
            title(app.UIAxes_3,sprintf('r_0=%.1f,PSNR=%.4f dB',r1,peaksnr1)); 
             %-------------------------------反向滤波-2--------------------------------
            r2=app.r2EditField.Value; %r值读取                           
            [peaksnr2,q0_inv2]=inverse_filter(app,r2,x,y,H1,Q0,lena);
            axis(app.UIAxes_4,'off'); %隐藏坐标轴
            imshow(uint8(255.*mat2gray(real(q0_inv2))),'Parent',app.UIAxes_4);
            title(app.UIAxes_4,sprintf('r_0=%.1f,PSNR=%.4f dB',r2,peaksnr2)); 
             %-------------------------------反向滤波最优参数r选取--------------------------------
            r0=0;
            peaksnr01=0;  
            peaksnr01_0 = ones(1,101);
            for r=0:0.01:1          
                [peaksnr11,~]=inverse_filter(app,r,x,y,H1,Q0,lena);  
                peaksnr01_0(1,int8(r*100+1)) = peaksnr11;
                if peaksnr11>peaksnr01
                    r0=r;
                    peaksnr01=peaksnr11;
                end                
            end
            app.rEditField.Value=r0;
            figure(1);
            r=0:0.01:1;
            plot(r, peaksnr01_0);
            xlabel('区域半径r0');
            ylabel('信噪比PSNR');
             %-------------------------------反向滤波函数--------------------------------
            function [peaksnr,q0_inv] = inverse_filter(~,r,x,y,H1,Q0,lena)            
                x_r=round(x*r);
                y_r=round(y*r);            
                H_r=ones(x,y).*100000;
                H_r(1:x_r,1:y_r)=H1(1:x_r,1:y_r);
                Q0_inv=Q0./H_r;
                q0_inv=ifft2(ifftshift(Q0_inv));
                peaksnr=PSNR_cal(app,lena,q0_inv,8); % PSNR                         
            end
             %-------------------------------维纳滤波-1--------------------------------
            K1=app.K1EditField.Value; %K值读取
            HM=abs(H1.*H1);                       
            W1=HM./(H1.*(HM+K1));         
            [peaksnr3,q_k1]=wiener_filter(app,W1,Q0,lena);
            axis(app.UIAxes_5,'off'); %隐藏坐标轴            
            imshow(uint8(255.*mat2gray(real(q_k1))),'Parent',app.UIAxes_5);
            title(app.UIAxes_5,sprintf('K=%.5f,PSNR=%.4f dB',K1,peaksnr3)); 
             %-------------------------------维纳滤波-2--------------------------------
            K2=app.K2EditField.Value; %K值读取   
            W2=HM./(H1.*(HM+K2));         
            [peaksnr4,q_k2]=wiener_filter(app,W2,Q0,lena);
            axis(app.UIAxes_6,'off'); %隐藏坐标轴            
            imshow(uint8(255.*mat2gray(real(q_k2))),'Parent',app.UIAxes_6);
            title(app.UIAxes_6,sprintf('K=%.5f,PSNR=%.4f dB',K2,peaksnr4)); 
             %-------------------------------维纳滤波最优参数K选取--------------------------------
            K0=0;
            peaksnr02=0;
            peaksnr02_0 = ones(1,101);
            for K=0.00008:0.0000001:0.00009                
                W=HM./(H1.*(HM+K)); 
                [peaksnr22,~]=wiener_filter(app,W,Q0,lena);
                peaksnr02_0(1,int8(K*10000000-800+1)) = peaksnr22;
                if peaksnr22>peaksnr02
                    K0=K;
                    peaksnr02=peaksnr22;
                end                
            end
            app.KEditField.Value=K0;
            figure(2);
            K=0.00008:0.0000001:0.00009;
            plot(K, peaksnr02_0);
            xlabel('频谱密度比值K');
            ylabel('信噪比PSNR');
             %-------------------------------维纳滤波函数--------------------------------
            function [peaksnr,q_k0] = wiener_filter(~,W_k,Q0,lena)
                Q0_k=ifft2(ifftshift(Q0));
                Q0_kn=awgn(Q0_k,40,'measured');
                Q0_n=ifftshift(fft2(Q0_kn));
                Q_k=Q0_n.*W_k;
                q_k0=ifft2(ifftshift(Q_k));
                peaksnr=PSNR_cal(app,lena,q_k0,8);          % PSNR                        
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '退 出';
            app.r1EditFieldLabel = uilabel(app.UIFigure);
            app.r1EditFieldLabel.Text = 'r1 =';
            app.r1EditField = uieditfield(app.UIFigure, 'numeric');
            app.r2EditFieldLabel = uilabel(app.UIFigure);
            app.r2EditFieldLabel.Text = 'r2 =';
            app.r2EditField = uieditfield(app.UIFigure, 'numeric');
            app.K1Label = uilabel(app.UIFigure);
            app.K1Label.Text = 'K1 =';
            app.K1EditField = uieditfield(app.UIFigure, 'numeric');
            app.K2EditFieldLabel = uilabel(app.UIFigure);
            app.K2EditFieldLabel.Text = 'K2 =';
            app.K2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '运 行';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Lena原图')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '降质图像')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '反向滤波-1')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '反向滤波-2')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, '维纳滤波-1')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, '维纳滤波-2')
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, '')
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = 'T =';
            app.TEditField = uieditfield(app.UIFigure, 'numeric');
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a =';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b =';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.rEditFieldLabel = uilabel(app.UIFigure);
            app.rEditFieldLabel.Text = '最佳r =';
            app.rEditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = '最佳K =';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EX_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end