classdef EX_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Button_5          matlab.ui.control.Button
        Button            matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
        UIAxes_3          matlab.ui.control.UIAxes
        m1Label           matlab.ui.control.Label
        m1EditField       matlab.ui.control.NumericEditField
        n1Label           matlab.ui.control.Label
        n1EditField       matlab.ui.control.NumericEditField
        m2EditFieldLabel  matlab.ui.control.Label
        m2EditField       matlab.ui.control.NumericEditField
        n2EditFieldLabel  matlab.ui.control.Label
        n2EditField       matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function best = ostu (~,I)  %用类间方差阈值求最佳阈值best           
            s=prod(size(I));
            N=zeros(256,1);
            T=zeros(256,1);
            
            for k=0:255
                num=size(find(I==k),1);
                N(k+1,1)=num;
            end
            
            for t=1:256
                a0=0; a1=0; b0=0; b1=0;
                % 一部分图像
                for m=1:t
                    a0=a0+N(m,1);
                    b0=b0+(m-1)*N(m,1);
                end            
                % 另一部分图像
                for m=t+1:256
                    a1=a1+N(m,1);
                    b1=b1+(m-1)*N(m,1);
                end
                w0=a0/s;
                w1=a1/s;
                % u0 u1
                u0=0; u1=0;
                for m=1:t
                    u0=u0+(m-1)*N(m,1)/s;
                end
                for m=t+1:256
                    u1=u1+(m-1)*N(m,1)/s;
                end
                u0=u0/w0;
                u1=u1/w1;                    
                T(t,1)=w0*w1*(u0-u1)*(u0-u1);
            end
            
            best=find(T==max(max(T)))-1;
        end
        
        function im = erode_exp3(~,r,dock3)    % 腐蚀函数                   
                ele=strel('disk',r,8);
                im=imerode(dock3,ele);                        
        end
        
        function im = dilate_exp3(~,r,dock3)  %膨胀函数                       
            ele=strel('disk',r,8);
            im=imdilate(dock3,ele);            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
             %-------------------------------原图像--------------------------------
            file="shiyan3.bmp";
            dock1=rgb2gray(imread(file));
            axis(app.UIAxes,'off'); %隐藏坐标轴
            imshow(dock1,'Parent',app.UIAxes); %显示原图
            title(app.UIAxes,'原始图像');
             %-------------------------------中值滤波-1--------------------------------
            m1=app.m1EditField.Value;
            n1=app.n1EditField.Value;
            dock2=medfilt2(dock1,[m1,n1]);
            axis(app.UIAxes_2,'off'); %隐藏坐标轴
            imshow(dock2,'Parent',app.UIAxes_2); %显示
            title(app.UIAxes_2,'中值滤波-1');
             %-------------------------------中值滤波-2--------------------------------
            m2=app.m2EditField.Value;
            n2=app.n2EditField.Value;
            dock3=medfilt2(dock1,[m2,n2]);
            axis(app.UIAxes_3,'off'); %隐藏坐标轴
            imshow(dock3,'Parent',app.UIAxes_3); %显示
            title(app.UIAxes_3,'中值滤波-2');           
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
             %-------------------------------原图像--------------------------------
            file="shiyan3.bmp";
            dock1=rgb2gray(imread(file));
            axis(app.UIAxes,'off'); %隐藏坐标轴
            imshow(dock1,'Parent',app.UIAxes); %显示原图
            title(app.UIAxes,'原始图像');
             %-------------------------------中值滤波--------------------------------
            dock2=medfilt2(dock1,[3,3]);
            axis(app.UIAxes_2,'off'); %隐藏坐标轴
            imshow(dock2,'Parent',app.UIAxes_2); %显示
            title(app.UIAxes_2,'中值滤波');
             %-------------------------------类间方差阈值分割--------------------------------
            t=ostu(app,dock2);            
            dock3=dock2;
            dock3(dock3<t)=0;
            dock3(dock3>=t)=255;
            axis(app.UIAxes_3,'off'); %隐藏坐标轴
            imshow(dock3,'Parent',app.UIAxes_3); %显示
            title(app.UIAxes_3,'类间方差阈值分割');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %-------------------------------原图像--------------------------------
            file="shiyan3.bmp";
            dock1=rgb2gray(imread(file));            
             %-------------------------------中值滤波--------------------------------
            dock2=medfilt2(dock1,[3,3]);            
             %-------------------------------类间方差阈值分割--------------------------------
            t=ostu(app,dock2);            
            dock3=dock2;
            dock3(dock3<t)=0;
            dock3(dock3>=t)=255;            
            %-------------------------------腐蚀运算--------------------------------
            r1=3;
            im1=erode_exp3(app,r1,dock3);
            imshow(im1,'Parent',app.UIAxes);
            title(app.UIAxes,sprintf('腐蚀: r=%d',r1));      
            r2=5;
            im2=erode_exp3(app,r2,dock3);
            imshow(im2,'Parent',app.UIAxes_2);
            title(app.UIAxes_2,sprintf('腐蚀: r=%d',r2));  
            r3=7;
            im3=erode_exp3(app,r3,dock3);
            imshow(im3,'Parent',app.UIAxes_3);
            title(app.UIAxes_3,sprintf('腐蚀: r=%d',r3));  
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %-------------------------------原图像--------------------------------
            file="shiyan3.bmp";
            dock1=rgb2gray(imread(file));            
            %-------------------------------中值滤波--------------------------------
            dock2=medfilt2(dock1,[3,3]);            
            %-------------------------------类间方差阈值分割--------------------------------
            t=ostu(app,dock2);            
            dock3=dock2;
            dock3(dock3<t)=0;
            dock3(dock3>=t)=255;            
            %-------------------------------腐蚀后膨胀运算--------------------------------
            r1=3;
            im1=erode_exp3(app,r1,dock3);            
            im4=dilate_exp3(app,r1,im1);
            imshow(im4,'Parent',app.UIAxes);
            title(app.UIAxes,sprintf('膨胀: r=%d',r1));      
            r2=5;
            im2=erode_exp3(app,r2,dock3);
            im5=dilate_exp3(app,r2,im2);
            imshow(im5,'Parent',app.UIAxes_2);
            title(app.UIAxes_2,sprintf('膨胀: r=%d',r2));  
            r3=7;
            im3=erode_exp3(app,r3,dock3);
            im6=dilate_exp3(app,r3,im3);
            imshow(im6,'Parent',app.UIAxes_3);
            title(app.UIAxes_3,sprintf('膨胀: r=%d',r3));  
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '退 出';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '中 值 滤 波';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '腐 蚀 运 算';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '间类方差阈值分割';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '膨 胀 运 算';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '图1')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '图2')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '图3')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.m1Label = uilabel(app.UIFigure);
            app.m1Label.Text = 'm1 =';
            app.m1EditField = uieditfield(app.UIFigure, 'numeric');
            app.n1Label = uilabel(app.UIFigure);
            app.n1Label.Text = 'n1 =';
            app.n1EditField = uieditfield(app.UIFigure, 'numeric');
            app.m2EditFieldLabel = uilabel(app.UIFigure);
            app.m2EditFieldLabel.Text = 'm2 =';
            app.m2EditField = uieditfield(app.UIFigure, 'numeric');
            app.n2EditFieldLabel = uilabel(app.UIFigure);
            app.n2EditFieldLabel.Text = 'n2 =';
            app.n2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '输入中值滤波范围(正值)：';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EX_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end