classdef EX_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_4  matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
        UIAxes_2  matlab.ui.control.UIAxes
        UIAxes_3  matlab.ui.control.UIAxes
        UIAxes_4  matlab.ui.control.UIAxes
        UIAxes_5  matlab.ui.control.UIAxes
        UIAxes_6  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [par1, par3] = Hough(~,BW)                      
            r_max=100;
            r_min=40;
            step_r=1;
            step_angle=pi/20;
            p=0.5;
            [m,n] = size(BW);
            size_r = round((r_max-r_min)/step_r)+1;
            size_angle = round(2*pi/step_angle);
            hough_space = zeros(m,n,size_r);
            [rows,cols] = find(BW);
            ecount = size(rows);
            % Hough变换
            % 将图像空间(x,y)对应到参数空间(a,b,r)
            % a = x-r*cos(angle)
            % b = y-r*sin(angle)
            for i=1:ecount
                for r=1:size_r
                    for k=1:size_angle
                        a = round(rows(i)-(r_min+(r-1)*step_r)*cos(k*step_angle));
                        b = round(cols(i)-(r_min+(r-1)*step_r)*sin(k*step_angle));
                        if(a>0 & a<=m & b>0 & b<=n)
                            hough_space(a,b,r) = hough_space(a,b,r)+1;
                        end
                    end
                end
            end
            % 搜索超过阈值的聚集点
            max_para = max(max(max(hough_space)));
            index = find(hough_space>=max_para*p);
            length = size(index);
            hough_circle = false(m,n);
            for i=1:ecount
                for k=1:length
                    par3 = floor(index(k)/(m*n))+1;
                    par2 = floor((index(k)-(par3-1)*(m*n))/m)+1;
                    par1 = index(k)-(par3-1)*(m*n)-(par2-1)*m;
                    if((rows(i)-par1)^2+(cols(i)-par2)^2<(r_min+(par3-1)*step_r)^2+5&...
                            (rows(i)-par1)^2+(cols(i)-par2)^2>(r_min+(par3-1)*step_r)^2-5)
                        hough_circle(rows(i),cols(i)) = true;
                    end
                end
            end
            % 打印检测结果
            for k=1:length
                par3 = floor(index(k)/(m*n))+1;
                par2 = floor((index(k)-(par3-1)*(m*n))/m)+1;
                par1 = index(k)-(par3-1)*(m*n)-(par2-1)*m;
                par3 = r_min+(par3-1)*step_r;              
            end            
            % viscircles([par2 par1],par3);
            par1=[par2 par1];        
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            shape=rgb2gray(imread('houghorg.bmp'));
            gauss=imnoise(shape,'gaussian',0,0.2);
            pepper=imnoise(shape,'salt & pepper',0.2);
            %------------------------------------原图像--------------------------------
            axis(app.UIAxes,'off'); %隐藏坐标轴
            imshow(shape,'Parent',app.UIAxes); %显示
            title(app.UIAxes,'原始图像');           
            %------------------------------------高斯噪声--------------------------------            
            axis(app.UIAxes_2,'off'); %隐藏坐标轴
            imshow(gauss,'Parent',app.UIAxes_2); %显示
            title(app.UIAxes_2,'高斯噪声');    
            %------------------------------------椒盐噪声--------------------------------            
            axis(app.UIAxes_3,'off'); %隐藏坐标轴
            imshow(pepper,'Parent',app.UIAxes_3); %显示
            title(app.UIAxes_3,'椒盐噪声');               
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            shape=rgb2gray(imread('houghorg.bmp'));
            gauss=imnoise(shape,'gaussian',0,0.2);
            gs0=medfilt2(gauss,[9,9]);       %滤波
            gs0(gs0>127)=255;                %线性提取
            gs0(gs0<128)=0;
            %------------------------------------求算子--------------------------------
            ps=cell(1,3);
            ps{1}=edge(gs0,'roberts');            % roberts算子
            axis(app.UIAxes,'off'); %隐藏坐标轴
            imshow(ps{1},'Parent',app.UIAxes); 
            title(app.UIAxes,'高斯噪声：roberts算子');
            ps{2}=edge(gs0,'sobel');              % sobel算子
            axis(app.UIAxes_2,'off'); %隐藏坐标轴
            imshow(ps{2},'Parent',app.UIAxes_2); 
            title(app.UIAxes_2,'高斯噪声：sobel算子');
            ps{3}=edge(gs0,'log');                % laplacian算子
            axis(app.UIAxes_3,'off'); %隐藏坐标轴
            imshow(ps{3},'Parent',app.UIAxes_3);
            title(app.UIAxes_3,'高斯噪声：laplacian算子');
            %------------------------------------重建图像--------------------------------            
            theta=0:0.1:2*pi;
            [par1,par3]=Hough(app,ps{1});
            axis(app.UIAxes_4,'off'); %隐藏坐标轴
            imshow(gauss,'Parent',app.UIAxes_4);
            hold(app.UIAxes_4,'on');            
            Circle1=par1(1)+par3*cos(theta);
            Circle2=par1(2)+par3*sin(theta);
            plot(app.UIAxes_4,Circle1,Circle2,'r','LineWidth',3);            
            title(app.UIAxes_4,'重建图像：roberts算子');
            [par1,par3]=Hough(app,ps{2});
            axis(app.UIAxes_5,'off'); %隐藏坐标轴
            imshow(gauss,'Parent',app.UIAxes_5);
            hold(app.UIAxes_5,'on');
            Circle1=par1(1)+par3*cos(theta);
            Circle2=par1(2)+par3*sin(theta);
            plot(app.UIAxes_5,Circle1,Circle2,'r','LineWidth',3);
            title(app.UIAxes_5,'重建图像：sobel算子');
            [par1,par3]=Hough(app,ps{3});
            axis(app.UIAxes_6,'off'); %隐藏坐标轴
            imshow(gauss,'Parent',app.UIAxes_6);
            hold(app.UIAxes_6,'on');
            Circle1=par1(1)+par3*cos(theta);
            Circle2=par1(2)+par3*sin(theta);
            plot(app.UIAxes_6,Circle1,Circle2,'r','LineWidth',3);
            title(app.UIAxes_6,'重构图像：laplacian算子');     
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            shape=rgb2gray(imread('houghorg.bmp'));
            pepper=imnoise(shape,'salt & pepper',0.2);
            p0=medfilt2(pepper,[9,9]);       %滤波
            p0(p0>127)=255;                  %线性提取
            p0(p0<128)=0;
            %------------------------------------求算子--------------------------------            
            ps=cell(1,3);
            ps{1}=edge(p0,'roberts');            % roberts算子
            axis(app.UIAxes,'off'); %隐藏坐标轴
            imshow(ps{1},'Parent',app.UIAxes); 
            title(app.UIAxes,'椒盐噪声：roberts算子');
            ps{2}=edge(p0,'sobel');              % sobel算子
            axis(app.UIAxes_2,'off'); %隐藏坐标轴
            imshow(ps{2},'Parent',app.UIAxes_2); 
            title(app.UIAxes_2,'椒盐噪声：sobel算子');
            ps{3}=edge(p0,'log');                % laplacian算子
            axis(app.UIAxes_3,'off'); %隐藏坐标轴
            imshow(ps{3},'Parent',app.UIAxes_3);
            title(app.UIAxes_3,'椒盐噪声：laplacian算子');
            %------------------------------------重建图像--------------------------------            
            theta=0:0.1:2*pi;
            [par1,par3]=Hough(app,ps{1});        
            axis(app.UIAxes_4,'off'); %隐藏坐标轴
            imshow(pepper,'Parent',app.UIAxes_4);
            hold(app.UIAxes_4,'on');            
            Circle1=par1(1)+par3*cos(theta);
            Circle2=par1(2)+par3*sin(theta);
            plot(app.UIAxes_4,Circle1,Circle2,'r','LineWidth',3);
            title(app.UIAxes_4,'重建图像：roberts算子');
            [par1,par3]=Hough(app,ps{2});        
            axis(app.UIAxes_5,'off'); %隐藏坐标轴
            imshow(pepper,'Parent',app.UIAxes_5);
            hold(app.UIAxes_5,'on');
            Circle1=par1(1)+par3*cos(theta);
            Circle2=par1(2)+par3*sin(theta);
            plot(app.UIAxes_5,Circle1,Circle2,'r','LineWidth',3);
            title(app.UIAxes_5,'重建图像：sobel算子');
            [par1,par3]=Hough(app,ps{3});        
            axis(app.UIAxes_6,'off'); %隐藏坐标轴
            imshow(pepper,'Parent',app.UIAxes_6);
            hold(app.UIAxes_6,'on');
            Circle1=par1(1)+par3*cos(theta);
            Circle2=par1(2)+par3*sin(theta);
            plot(app.UIAxes_6,Circle1,Circle2,'r','LineWidth',3);
            title(app.UIAxes_6,'重构图像：laplacian算子');     
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '退 出';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '噪 声 产 生';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '高 斯 噪 声';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '椒 盐 噪 声';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '图1')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '图2')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '图3')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '图4')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, '图5')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, '图6')
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EX_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end