classdef EX_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        Button            matlab.ui.control.Button
        Label             matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
        Button_2          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        Label_2           matlab.ui.control.Label
        EditField_2       matlab.ui.control.NumericEditField
        UIAxes_2          matlab.ui.control.UIAxes
        dBEditFieldLabel  matlab.ui.control.Label
        dBEditField       matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        EditField_3       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
              closereq();
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            py.EX_5_train.test();
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            num = app.EditField.Value;
            py.EX_5_test.test(num);
            a = load('test.mat');
            axis(app.UIAxes,'off'); %隐藏坐标轴
            imshow(a.test_image,'Parent',app.UIAxes); %显示
            title(app.UIAxes,sprintf('实际值：%d    预测值：%d', a.label, a.result));
            app.EditField_2.Value = a.accuracy;
            %-------------------------------加噪--------------------------------
            w = app.dBEditField.Value;
            test_image_n = ones(10000,28,28);    
            for i=1:10000
                str = reshape(a.test_images(i,:,:),[28 28]);
                str = awgn(double(str),w,'measured');
                test_image_n(i,:,:) = uint8(str);
            end
            save ('dataNew_n.mat','test_image_n')
            py.EX_5_test.test_n(num);
            b = load('test_n.mat');
            axis(app.UIAxes_2,'off'); %隐藏坐标轴
            imshow(b.test_image,'Parent',app.UIAxes_2); %显示
            title(app.UIAxes_2,sprintf('加噪后/n实际值：%d    预测值：%d', a.label, b.result));
            app.EditField_3.Value = b.accuracy;
            %-------------------------------绘图--------------------------------
            accur = ones(1,40);
            for w=1:40
                for i=1:10000
                    str = reshape(a.test_images(i,:,:),[28 28]);
                    str = awgn(double(str),w,'measured');
                    test_image_n(i,:,:) = uint8(str);
                end
                save ('dataNew_n.mat','test_image_n')
                py.EX_5_test.test_n(num);
                b = load('test_n.mat');
                accur(1,w) = b.accuracy;
            end
            t = 1:40;
            plot(t,accur);
            xlabel('信噪比/dB');
            ylabel('正确率/%');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '图1')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '退 出';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '图片编号(1~10000)';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '训 练 模 型';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '预 测 验 证';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '正确率(不需要输入)';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '图1')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.dBEditFieldLabel = uilabel(app.UIFigure);
            app.dBEditFieldLabel.Text = '噪声（dB）';
            app.dBEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '正确率(不需要输入)';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EX_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end